classdef MainProgram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Menu                           matlab.ui.Figure
        StatusPanel                    matlab.ui.container.Panel
        StatusLamp                     matlab.ui.control.Lamp
        IdleLabel                      matlab.ui.control.Label
        FigureandTableGenerationPanel  matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        MainArticleTab                 matlab.ui.container.Tab
        Figure2Button                  matlab.ui.control.Button
        Figure1Button                  matlab.ui.control.Button
        Figure3AButton                 matlab.ui.control.Button
        Figure3BButton                 matlab.ui.control.Button
        Table1Button                   matlab.ui.control.Button
        SupplementTab                  matlab.ui.container.Tab
        FigureA3Button                 matlab.ui.control.Button
        FigureA4Button                 matlab.ui.control.Button
        FigureA5Button                 matlab.ui.control.Button
        FigureA6Button                 matlab.ui.control.Button
        TableA1Button                  matlab.ui.control.Button
        TableA2Button                  matlab.ui.control.Button
        RunExperimentsPanel            matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        EyesOpenClosedTab              matlab.ui.container.Tab
        RunParameterfinderButton       matlab.ui.control.Button
        RunValidationtestsButton       matlab.ui.control.Button
        RunRealtimetestsButton         matlab.ui.control.Button
        RunOFFdatatestsButton          matlab.ui.control.Button
        LLabel                         matlab.ui.control.Label
        EyesOpenTab                    matlab.ui.container.Tab
        RunParameterfinderButton_2     matlab.ui.control.Button
        FindBestparametersButton       matlab.ui.control.Button
        RunvalidationtestsButton       matlab.ui.control.Button
        LLabel_2                       matlab.ui.control.Label
        EyesClosedTab                  matlab.ui.container.Tab
        RunParameterfinderButton_3     matlab.ui.control.Button
        FindBestparametersButton_2     matlab.ui.control.Button
        RunvalidationtestsButton_2     matlab.ui.control.Button
        LLabel_3                       matlab.ui.control.Label
        DatasetsPanel                  matlab.ui.container.Panel
        UNMdatasetButton               matlab.ui.control.Button
        IowadatasetButton              matlab.ui.control.Button
        UNMOFFdatasetButton            matlab.ui.control.Button
        OtherMethodsPanel              matlab.ui.container.Panel
        Vanneste2018Button             matlab.ui.control.Button
        Chaturvedi2017Button           matlab.ui.control.Button
        Yuvaraj2018Button              matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        httpsdoiorg101016jparkreldis202008001Label  matlab.ui.control.Label
        CodewrittenbyMdFahimAnjumEmailmdfahimanjumuiowaeduLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        curr_dir=pwd;      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pwd)
            [directory,~,~] = fileparts(mfilename('fullpath')); %    GET THE MLAPP FILE PATH !!!!
            app.curr_dir=directory;
            cd(app.curr_dir);
            message = sprintf('1# Please make sure to add the path of this file (MainProgram.mlapp) to Matlab before running\n2# Make sure that the datasets are in the Dataset folder');
            fig = app.Menu;
            uialert(fig,message,'Please check.','Icon','warning');
        end

        % Button pushed function: Figure1Button
        function Figure1ButtonPushed(app, event)
            %disp('hahahahaha');            
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Fig 1\code\';
            display_psdA();
            display_psdB();
            cd ../;
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';
        end

        % Button pushed function: Figure2Button
        function Figure2ButtonPushed(app, event)
            %disp('hahahahaha');            
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Fig 2\Code\';
            Main();
            cd ../;
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';            
        end

        % Button pushed function: Figure3AButton
        function Figure3AButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Fig 3A\Code\';
            generate_results();
            cd ../;
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';            
        end

        % Button pushed function: Figure3BButton
        function Figure3BButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Fig 3B\Code\';
            generate_results();
            cd ../;
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';  
        end

        % Button pushed function: Table1Button
        function Table1ButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Table 1\Code\';
            main();
            cd ../;
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';              
        end

        % Button pushed function: FigureA3Button
        function FigureA3ButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Fig Supp A3\Code\';
            PSD_LPC_inspection();
            cd ../;
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                  
        end

        % Button pushed function: FigureA4Button
        function FigureA4ButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Fig Supp A4';            
            winopen('Fig A4.pdf');
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                   
        end

        % Button pushed function: FigureA5Button
        function FigureA5ButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Fig Supp A5\Code\';
            main();
            cd ../;
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                 
        end

        % Button pushed function: FigureA6Button
        function FigureA6ButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Fig Supp A6\Code\';
            ACC_expanding_time();
            SoftScore_vs_time();
            SoftScore_vs_timeTr();
            cd ../;
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';              
        end

        % Button pushed function: TableA1Button
        function TableA1ButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Table Supp A1';
            table_A1();
            cd ../;
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                  
        end

        % Button pushed function: TableA2Button
        function TableA2ButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Table Supp A2';
            table_A2();
            cd ../;
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                      
        end

        % Button pushed function: IowadatasetButton
        function IowadatasetButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd ../;
            cd 'Dataset';
            cd 'IowaDataset';
            winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                    
        end

        % Button pushed function: UNMdatasetButton
        function UNMdatasetButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd ../;
            cd 'Dataset';
            cd 'UNMDataset';
            winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                    
        end

        % Button pushed function: UNMOFFdatasetButton
        function UNMOFFdatasetButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd ../;
            cd 'Dataset';
            cd 'UNMDataset_OFF';
            winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                            
        end

        % Button pushed function: RunParameterfinderButton
        function RunParameterfinderButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Best Channel Selection OptVer';
            cd 'Code';
            hyperparameter_finder();            
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                 
        end

        % Button pushed function: RunValidationtestsButton
        function RunValidationtestsButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'ValidationTests';
            cd 'Code';
            performance_evaluation();            
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                  
        end

        % Button pushed function: RunRealtimetestsButton
        function RunRealtimetestsButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'RealTimeTest OptVer';
            cd 'Code';
            performance_evaluation();            
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';            
        end

        % Button pushed function: RunOFFdatatestsButton
        function RunOFFdatatestsButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'UNM_OFFresult';
            cd 'Code';
            performance_evaluation();            
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                 
        end

        % Button pushed function: RunParameterfinderButton_2
        function RunParameterfinderButton_2Pushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'EyesOpenAnalysis OptVer';
            cd 'Code';
            hyperparameter_finder();            
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                
        end

        % Button pushed function: FindBestparametersButton
        function FindBestparametersButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'EyesOpenAnalysis OptVer';
            cd 'Code';
            best_channel_finder();            
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed. \nCHECK THE COMMAND WINDOW FOR RESULTS');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';               
        end

        % Button pushed function: RunParameterfinderButton_3
        function RunParameterfinderButton_3Pushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'EyesClosedAnalysis OptVer';
            cd 'Code';
            hyperparameter_finder();            
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';               
        end

        % Button pushed function: RunvalidationtestsButton
        function RunvalidationtestsButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'EyesOpenAnalysis OptVer';
            cd 'Code';
            performance_evaluation();            
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed. \nCHECK THE COMMAND WINDOW FOR RESULTS');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                 
        end

        % Button pushed function: FindBestparametersButton_2
        function FindBestparametersButton_2Pushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'EyesClosedAnalysis OptVer';
            cd 'Code';
            best_channel_finder();            
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed. \nCHECK THE COMMAND WINDOW FOR RESULTS');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';              
        end

        % Button pushed function: RunvalidationtestsButton_2
        function RunvalidationtestsButton_2Pushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'EyesClosedAnalysis OptVer';
            cd 'Code';
            performance_evaluation();            
            %winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed. \nCHECK THE COMMAND WINDOW FOR RESULTS');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';               
        end

        % Button pushed function: Chaturvedi2017Button
        function Chaturvedi2017ButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Chaturvedi 2017';
            cd 'Code';         
            winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed.');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';               
        end

        % Button pushed function: Vanneste2018Button
        function Vanneste2018ButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Vanneste 2018';
            cd 'Code';         
            winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed.');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                  
        end

        % Button pushed function: Yuvaraj2018Button
        function Yuvaraj2018ButtonPushed(app, event)
            cd(app.curr_dir);
            app.StatusLamp.Color=[ 1 0 0];
            app.IdleLabel.Text='Busy';
            fig = app.Menu;
            uiprogressdlg(fig,'Title','Please Wait', 'Message','Performing the tasks');
            cd 'Yuvaraj 2018';
            cd 'Code';         
            winopen(pwd);
            cd(app.curr_dir);
            message = sprintf('Success! \n Operation completed.');
            uialert(fig,message,'Success','Icon','success');
            app.StatusLamp.Color=[ 0 1 0];
            app.IdleLabel.Text='Idle';                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Menu = uifigure('Visible', 'off');
            app.Menu.Name = 'UI Figure';
            app.StatusPanel = uipanel(app.Menu);
            app.StatusPanel.Title = 'Status';
            app.StatusLamp = uilamp(app.StatusPanel);
            app.IdleLabel = uilabel(app.StatusPanel);
            app.IdleLabel.Text = 'Idle';
            app.FigureandTableGenerationPanel = uipanel(app.Menu);
            app.FigureandTableGenerationPanel.Title = 'Figure and Table Generation';
            app.TabGroup = uitabgroup(app.FigureandTableGenerationPanel);
            app.MainArticleTab = uitab(app.TabGroup);
            app.MainArticleTab.Title = 'Main Article';
            app.Figure2Button = uibutton(app.MainArticleTab, 'push');
            app.Figure2Button.ButtonPushedFcn = createCallbackFcn(app, @Figure2ButtonPushed, true);
            app.Figure2Button.Text = 'Figure 2';
            app.Figure1Button = uibutton(app.MainArticleTab, 'push');
            app.Figure1Button.ButtonPushedFcn = createCallbackFcn(app, @Figure1ButtonPushed, true);
            app.Figure1Button.Text = 'Figure 1';
            app.Figure3AButton = uibutton(app.MainArticleTab, 'push');
            app.Figure3AButton.ButtonPushedFcn = createCallbackFcn(app, @Figure3AButtonPushed, true);
            app.Figure3AButton.Text = 'Figure 3A';
            app.Figure3BButton = uibutton(app.MainArticleTab, 'push');
            app.Figure3BButton.ButtonPushedFcn = createCallbackFcn(app, @Figure3BButtonPushed, true);
            app.Figure3BButton.Text = 'Figure 3B';
            app.Table1Button = uibutton(app.MainArticleTab, 'push');
            app.Table1Button.ButtonPushedFcn = createCallbackFcn(app, @Table1ButtonPushed, true);
            app.Table1Button.Text = 'Table 1';
            app.SupplementTab = uitab(app.TabGroup);
            app.SupplementTab.Title = 'Supplement';
            app.FigureA3Button = uibutton(app.SupplementTab, 'push');
            app.FigureA3Button.ButtonPushedFcn = createCallbackFcn(app, @FigureA3ButtonPushed, true);
            app.FigureA3Button.Text = 'Figure A3';
            app.FigureA4Button = uibutton(app.SupplementTab, 'push');
            app.FigureA4Button.ButtonPushedFcn = createCallbackFcn(app, @FigureA4ButtonPushed, true);
            app.FigureA4Button.Text = 'Figure A4';
            app.FigureA5Button = uibutton(app.SupplementTab, 'push');
            app.FigureA5Button.ButtonPushedFcn = createCallbackFcn(app, @FigureA5ButtonPushed, true);
            app.FigureA5Button.Text = 'Figure A5';
            app.FigureA6Button = uibutton(app.SupplementTab, 'push');
            app.FigureA6Button.ButtonPushedFcn = createCallbackFcn(app, @FigureA6ButtonPushed, true);
            app.FigureA6Button.Text = 'Figure A6';
            app.TableA1Button = uibutton(app.SupplementTab, 'push');
            app.TableA1Button.ButtonPushedFcn = createCallbackFcn(app, @TableA1ButtonPushed, true);
            app.TableA1Button.Text = 'Table A1';
            app.TableA2Button = uibutton(app.SupplementTab, 'push');
            app.TableA2Button.ButtonPushedFcn = createCallbackFcn(app, @TableA2ButtonPushed, true);
            app.TableA2Button.Text = 'Table A2';
            app.RunExperimentsPanel = uipanel(app.Menu);
            app.RunExperimentsPanel.Title = 'Run Experiments';
            app.TabGroup2 = uitabgroup(app.RunExperimentsPanel);
            app.EyesOpenClosedTab = uitab(app.TabGroup2);
            app.EyesOpenClosedTab.Title = 'Eyes Open + Closed';
            app.RunParameterfinderButton = uibutton(app.EyesOpenClosedTab, 'push');
            app.RunParameterfinderButton.ButtonPushedFcn = createCallbackFcn(app, @RunParameterfinderButtonPushed, true);
            app.RunParameterfinderButton.Text = 'Run Parameter finder';
            app.RunValidationtestsButton = uibutton(app.EyesOpenClosedTab, 'push');
            app.RunValidationtestsButton.ButtonPushedFcn = createCallbackFcn(app, @RunValidationtestsButtonPushed, true);
            app.RunValidationtestsButton.Text = 'Run Validation tests';
            app.RunRealtimetestsButton = uibutton(app.EyesOpenClosedTab, 'push');
            app.RunRealtimetestsButton.ButtonPushedFcn = createCallbackFcn(app, @RunRealtimetestsButtonPushed, true);
            app.RunRealtimetestsButton.Text = 'Run Real time tests';
            app.RunOFFdatatestsButton = uibutton(app.EyesOpenClosedTab, 'push');
            app.RunOFFdatatestsButton.ButtonPushedFcn = createCallbackFcn(app, @RunOFFdatatestsButtonPushed, true);
            app.RunOFFdatatestsButton.Text = 'Run OFF data tests';
            app.LLabel = uilabel(app.EyesOpenClosedTab);
            app.LLabel.Text = {'Note:'; '===='; ' '; '(1) Running these function might take '; '     hours depending on the machine'; ''; '(2) Running these functions might alter'; '      the figure and table generation'; ''; '(3) At least 16 GB of RAM and graphics'; '      card needed to run these tests'; ''; '(4) Expect +/-1% changes in results'; '      due to random sampling and data'; '      shuffle during validations'};
            app.EyesOpenTab = uitab(app.TabGroup2);
            app.EyesOpenTab.Title = 'Eyes Open';
            app.RunParameterfinderButton_2 = uibutton(app.EyesOpenTab, 'push');
            app.RunParameterfinderButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunParameterfinderButton_2Pushed, true);
            app.RunParameterfinderButton_2.Text = 'Run Parameter finder';
            app.FindBestparametersButton = uibutton(app.EyesOpenTab, 'push');
            app.FindBestparametersButton.ButtonPushedFcn = createCallbackFcn(app, @FindBestparametersButtonPushed, true);
            app.FindBestparametersButton.Text = 'Find Best parameters';
            app.RunvalidationtestsButton = uibutton(app.EyesOpenTab, 'push');
            app.RunvalidationtestsButton.ButtonPushedFcn = createCallbackFcn(app, @RunvalidationtestsButtonPushed, true);
            app.RunvalidationtestsButton.Text = 'Run validation tests';
            app.LLabel_2 = uilabel(app.EyesOpenTab);
            app.LLabel_2.Text = {'Note:'; '===='; ' '; '(1) Running these function might take '; '     hours depending on the machine'; ''; '(2) Running these functions might alter'; '      the figure and table generation'; ''; '(3) At least 16 GB of RAM and graphics'; '      card needed to run these tests'; ''; '(4) Expect +/-1% changes in results'; '      due to random sampling and data'; '      shuffle during validations'};
            app.EyesClosedTab = uitab(app.TabGroup2);
            app.EyesClosedTab.Title = 'Eyes Closed';
            app.RunParameterfinderButton_3 = uibutton(app.EyesClosedTab, 'push');
            app.RunParameterfinderButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunParameterfinderButton_3Pushed, true);
            app.RunParameterfinderButton_3.Text = 'Run Parameter finder';
            app.FindBestparametersButton_2 = uibutton(app.EyesClosedTab, 'push');
            app.FindBestparametersButton_2.ButtonPushedFcn = createCallbackFcn(app, @FindBestparametersButton_2Pushed, true);
            app.FindBestparametersButton_2.Text = 'Find Best parameters';
            app.RunvalidationtestsButton_2 = uibutton(app.EyesClosedTab, 'push');
            app.RunvalidationtestsButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunvalidationtestsButton_2Pushed, true);
            app.RunvalidationtestsButton_2.Text = 'Run validation tests';
            app.LLabel_3 = uilabel(app.EyesClosedTab);
            app.LLabel_3.Text = {'Note:'; '===='; ' '; '(1) Running these function might take '; '     hours depending on the machine'; ''; '(2) Running these functions might alter'; '      the figure and table generation'; ''; '(3) At least 16 GB of RAM and graphics'; '      card needed to run these tests'; ''; '(4) Expect +/-1% changes in results'; '      due to random sampling and data'; '      shuffle during validations'};
            app.DatasetsPanel = uipanel(app.Menu);
            app.DatasetsPanel.Title = 'Datasets';
            app.UNMdatasetButton = uibutton(app.DatasetsPanel, 'push');
            app.UNMdatasetButton.ButtonPushedFcn = createCallbackFcn(app, @UNMdatasetButtonPushed, true);
            app.UNMdatasetButton.Text = 'UNM dataset';
            app.IowadatasetButton = uibutton(app.DatasetsPanel, 'push');
            app.IowadatasetButton.ButtonPushedFcn = createCallbackFcn(app, @IowadatasetButtonPushed, true);
            app.IowadatasetButton.Text = 'Iowa dataset';
            app.UNMOFFdatasetButton = uibutton(app.DatasetsPanel, 'push');
            app.UNMOFFdatasetButton.ButtonPushedFcn = createCallbackFcn(app, @UNMOFFdatasetButtonPushed, true);
            app.UNMOFFdatasetButton.Text = 'UNM OFF dataset';
            app.OtherMethodsPanel = uipanel(app.Menu);
            app.OtherMethodsPanel.Title = 'Other Methods';
            app.Vanneste2018Button = uibutton(app.OtherMethodsPanel, 'push');
            app.Vanneste2018Button.ButtonPushedFcn = createCallbackFcn(app, @Vanneste2018ButtonPushed, true);
            app.Vanneste2018Button.Text = 'Vanneste 2018';
            app.Chaturvedi2017Button = uibutton(app.OtherMethodsPanel, 'push');
            app.Chaturvedi2017Button.ButtonPushedFcn = createCallbackFcn(app, @Chaturvedi2017ButtonPushed, true);
            app.Chaturvedi2017Button.Text = 'Chaturvedi 2017';
            app.Yuvaraj2018Button = uibutton(app.OtherMethodsPanel, 'push');
            app.Yuvaraj2018Button.ButtonPushedFcn = createCallbackFcn(app, @Yuvaraj2018ButtonPushed, true);
            app.Yuvaraj2018Button.Text = 'Yuvaraj 2018';
            app.Label = uilabel(app.Menu);
            app.Label.Text = 'Linear predictive coding distinguishes spectral EEG features of Parkinson''s disease';
            app.httpsdoiorg101016jparkreldis202008001Label = uilabel(app.Menu);
            app.httpsdoiorg101016jparkreldis202008001Label.Text = 'https://doi.org/10.1016/j.parkreldis.2020.08.001';
            app.CodewrittenbyMdFahimAnjumEmailmdfahimanjumuiowaeduLabel = uilabel(app.Menu);
            app.CodewrittenbyMdFahimAnjumEmailmdfahimanjumuiowaeduLabel.Text = 'Code written by: Md Fahim Anjum  Email: mdfahim-anjum@uiowa.edu';
            % Show the figure after all components are created
            app.Menu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainProgram(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Menu)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Menu)
        end
    end
end